classdef ForceSight_AppDesignerFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        V20Label                       matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        SuggestionsPanel               matlab.ui.container.Panel
        SuggestionsDropDown            matlab.ui.control.DropDown
        SuggestionsTable               matlab.ui.control.Table
        OptionsPanel                   matlab.ui.container.Panel
        LatexDipCheckBox               matlab.ui.control.CheckBox
        UsemetricvaluescmCheckBox      matlab.ui.control.CheckBox
        GenerateSuggestionsButton      matlab.ui.control.Button
        RipstopRolledCheckBox          matlab.ui.control.CheckBox
        RangeCheckBox                  matlab.ui.control.CheckBox
        ValueCheckBox                  matlab.ui.control.CheckBox
        DesiredForceNLabel             matlab.ui.control.Label
        PressureRangemBarLabel         matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        pressure_max                   matlab.ui.control.NumericEditField
        pressure_min                   matlab.ui.control.NumericEditField
        force                          matlab.ui.control.NumericEditField
        ManufacturingMethodsLabel      matlab.ui.control.Label
        BEMISRolledCheckBox            matlab.ui.control.CheckBox
        LatexTubeCheckBox              matlab.ui.control.CheckBox
        LatexSheetCheckBox             matlab.ui.control.CheckBox
        PredictorValuePanel            matlab.ui.container.Panel
        ThicknessinchEditField         matlab.ui.control.NumericEditField
        ThicknessinchEditFieldLabel    matlab.ui.control.Label
        DiameterinchEditField          matlab.ui.control.NumericEditField
        DiameterinchEditFieldLabel     matlab.ui.control.Label
        LengthinchEditField            matlab.ui.control.NumericEditField
        LengthinchEditFieldLabel       matlab.ui.control.Label
        PredictorRangePanel            matlab.ui.container.Panel
        StandardIncrementCheckBox      matlab.ui.control.CheckBox
        increment_thickness            matlab.ui.control.Slider
        increment_thickness_slider_label  matlab.ui.control.Label
        increment_diameter             matlab.ui.control.Slider
        increment_diameter_slider_label  matlab.ui.control.Label
        increment_length               matlab.ui.control.Slider
        increment_length_slider_label  matlab.ui.control.Label
        thickness_max                  matlab.ui.control.NumericEditField
        thickness_max_label            matlab.ui.control.Label
        thickness_min_label            matlab.ui.control.Label
        thickness_min                  matlab.ui.control.NumericEditField
        diameter_max                   matlab.ui.control.NumericEditField
        diameter_max_label             matlab.ui.control.Label
        diameter_min_label             matlab.ui.control.Label
        diameter_min                   matlab.ui.control.NumericEditField
        length_max                     matlab.ui.control.NumericEditField
        length_max_label               matlab.ui.control.Label
        length_min                     matlab.ui.control.NumericEditField
        length_min_label               matlab.ui.control.Label
        increment_thickness_label      matlab.ui.control.Label
        increment_diameter_label       matlab.ui.control.Label
        increment_length_label         matlab.ui.control.Label
    end

    %This code was written/generated by Joanne Joe and Jordan Savage
    properties (Access = private)
        Suggestions % Cell array of suggestions for each manufacturing method
    end
    
    methods (Access = private)
        
        function result = conditionalFcn(~, condition, a, b)
            if condition
                result = a;
            else
                result = b;
            end
        end
        
        function window = generateProgressBar(app)
            fig = app.UIFigure;
            window = uiprogressdlg(fig, 'Title', 'Progress', 'Message', 'Generating Suggestions...', 'Indeterminate','on');
        end

        function result_inch = convertToInch(~, val)
            result_inch = val * 0.393701;
        end

        function result_cm = convertToCm(~, val)
            result_cm = val * 2.54;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateSuggestionsButton
        function GenerateSuggestionsButtonPushed(app, event)
            try
                % Check if manufacturing method selected
                if app.LatexSheetCheckBox.Value == 0 && app.LatexTubeCheckBox.Value == 0 ...
                        && app.BEMISRolledCheckBox.Value == 0 && app.RipstopRolledCheckBox.Value == 0 ...
                        && app.LatexDipCheckBox.Value == 0
                    message = 'Please select a manufacturing method.';
                    uialert(app.UIFigure, message, 'Error');
                    return;
                end

                % Check if either range or value is selected
                if app.ValueCheckBox.Value == 0 && app.RangeCheckBox.Value == 0
                    errordlg('Please input either values or a range for length, diameter and thickness.', 'Range/Value Not Selected');
                   % message = 'Please input either values or a range for length, diameter and thickness.'
                  %  uialert(app.UIFigure, message, 'Error');
                    return;
                end
                
                % Progress window
                window = generateProgressBar(app);
                drawnow


                % Generate predictor arrays
                if app.RangeCheckBox.Value == 1
                    if app.increment_length.Value == 0 %check if they entered 0 as increment value
                        errordlg('Increment value for length cannot be 0', 'Input Error');
                    elseif app.increment_diameter.Value == 0
                        errordlg('Increment value for diameter cannot be 0', 'Input Error');
                    elseif app.increment_thickness.Value == 0
                        errordlg('Increment value for thickness cannot be 0', 'Input Error');
                    elseif app.UsemetricvaluescmCheckBox.Value == 1     % Convert to imperial from metric
                            length_array = convertToInch(app, app.length_min.Value):app.increment_length.Value:convertToInch(app, app.length_max.Value);
                            diameter_array = convertToInch(app, app.diameter_min.Value):app.increment_diameter.Value:convertToInch(app, app.diameter_max.Value);
                            thickness_array = convertToInch(app, app.thickness_min.Value):app.increment_thickness.Value:convertToInch(app, app.thickness_max.Value);
                    else
                            length_array = app.length_min.Value:app.increment_length.Value:app.length_max.Value;
                            diameter_array = app.diameter_min.Value:app.increment_diameter.Value:app.diameter_max.Value;
                            thickness_array = app.thickness_min.Value:app.increment_thickness.Value:app.thickness_max.Value;
                   end
    
                    if app.StandardIncrementCheckBox.Value == 1
                        app.diameter_min.Value = conditionalFcn(app, app.diameter_min.Value < 0.06, 0.0625, round(app.diameter_min.Value*16)/16);
                        app.diameter_max.Value = round(app.diameter_max.Value*16)/16;
                        app.increment_diameter.Value = 0.0625;
                        app.increment_diameter_label.Text = '0.0625';
                    end
                end
    
                if app.ValueCheckBox.Value == 1 %single predictor values not an array
                    if app.UsemetricvaluescmCheckBox.Value == 1
                        length_array = convertToInch(app, app.LengthinchEditField.Value);
                        diameter_array = convertToInch(app, app.DiameterinchEditField.Value);
                        thickness_array = convertToInch(app, app.ThicknessinchEditField.Value);
                        %changed from convertToCm
                    else
                        length_array = app.LengthinchEditField.Value;
                        diameter_array = app.DiameterinchEditField.Value;
                        thickness_array = app.ThicknessinchEditField.Value;
                    end
                end
                pressure_increment = 10;
                pressure_array = app.pressure_min.Value:pressure_increment:app.pressure_max.Value;
                manuf_array = [];
    
                if app.LatexSheetCheckBox.Value == 1
                    manuf_array = [manuf_array; {'Latex Sheet'}];
                end
                if app.LatexTubeCheckBox.Value == 1
                    manuf_array = [manuf_array; {'Latex Tube'}];
                end
                if app.LatexDipCheckBox.Value == 1
                    manuf_array = [manuf_array; {'Latex Dip'}];
                end
                if app.BEMISRolledCheckBox.Value == 1 
                    manuf_array = [manuf_array; {'BMIS Rolled'}];
                end
                if app.RipstopRolledCheckBox.Value == 1
                    manuf_array = [manuf_array; {'Rolled Ripstop'}];
                end
                
                % Generate prediction data
                test_data = combinations(pressure_array, length_array, diameter_array, thickness_array, manuf_array);
                test_data = renamevars(test_data,["pressure_array","length_array","diameter_array","thickness_array","manuf_array"],["Pressure","Length","Diameter","Thickness","FabricationMethod"]);
                %disp(test_data)
                %disp('----------------------------')
                model = load("WideNeuralNetExpanded_V3.mat");
                % Predict forces
                %yfit = model.trainedModelWide100.predictFcn(test_data);
                yfit = model.Tanh_Std_100N_L2_V3_M2.predictFcn(test_data);
                predicted_forces = array2table(yfit);
                %disp(predicted_forces)
                % Final dataset including predicted forces
                final_data = [test_data, predicted_forces];
                final_data.Properties.VariableNames{6} = 'Force';
    
                % Find the top 5 suggestions
                final_data.Mins = abs(final_data.Force - app.force.Value);
                if any(final_data.Force - app.force.Value < -1)
                    errordlg('Warning some predictions may be below desired force.', 'Low Force Predictions')
                end 
                
                % Initialize the suggestions cell array properly
                suggestions = cell(length(manuf_array), 2);
                
                for i = 1:length(manuf_array)
                    temp_array = final_data(contains(final_data.FabricationMethod, manuf_array{i}), :);
                    [~, idx] = mink(temp_array.Mins, 5);
                    array_to_add = temp_array(idx,:);
                    
                    % Convert to metric if needed
                    if app.UsemetricvaluescmCheckBox.Value == 1
                        array_to_add.Length = array_to_add.Length .* 2.54;
                        array_to_add.Diameter = array_to_add.Diameter .* 2.54;
                        array_to_add.Thickness = array_to_add.Thickness .* 2.54;
                    end
                    %  manuf_array(i,2) = {array_to_add};
                    % manuf_array{i,2}(:,2:4);
                    % 
                    % if app.UsemetricvaluescmCheckBox.Value == 1
                    %     manuf_array{i,2}(:,2:4) = manuf_array{i,2}(:,2:4) .* 2.54;
                    % end
                    
                    % Store the results in the suggestions cell array
                    suggestions{i, 1} = manuf_array{i};
                    suggestions{i, 2} = array_to_add;
                end
                
                % Store suggestions into global variable
                app.Suggestions = suggestions;
                %app.Suggestions = manuf_array;
                % Populate dropdown and grid
                app.SuggestionsDropDown.Enable = "on";
                app.SuggestionsTable.Enable = "on";
   
                % app.SuggestionsDropDown.Items = manuf_array(:,1);
                % app.SuggestionsDropDown.ItemsData = 1:height(manuf_array);    
                app.SuggestionsDropDown.Items = suggestions(:,1); % Change from manuf_array to suggestions
                app.SuggestionsDropDown.ItemsData = 1:size(suggestions, 1); % Change from height(manuf_array) to size(suggestions, 1)
                app.SuggestionsDropDown.Value = 1; 

                %app.SuggestionsTable.Data = manuf_array{1,2}(:,1:6);
                app.SuggestionsTable.Data = suggestions{1,2}(:,1:6);
                close(window);
            catch ME
                % Build the error message
                errorMessage = sprintf('Error: %s\nMessage: %s\n', ME.identifier, ME.message);
                for k = 1:length(ME.stack)
                    errorMessage = sprintf('%sFile: %s\nFunction: %s\nLine: %d\n', ...
                        errorMessage, ME.stack(k).file, ME.stack(k).name, ME.stack(k).line);
                end
                
                % Display the error message in an alert dialog
                uialert(app.UIFigure, errorMessage, 'Error');
            end
        end

        % Value changed function: increment_length
        function increment_lengthValueChanged(app, event)
            % Snap increment value to nearest tick on slider
            [~, idx_minor] = min(abs(event.Source.Value - event.Source.MinorTicks(:)));

            event.Source.Value = event.Source.MinorTicks(idx_minor);
            app.increment_length.Value = event.Source.MinorTicks(idx_minor);
            app.increment_length_label.Text = num2str(app.increment_length.Value);
            
        end

        % Value changed function: increment_diameter
        function increment_diameterValueChanged(app, event)
            % Snap increment value to nearest tick on slider
            [~, idx_minor] = min(abs(event.Source.Value - event.Source.MinorTicks(:)));

            event.Source.Value = event.Source.MinorTicks(idx_minor);
            app.increment_diameter.Value = event.Source.MinorTicks(idx_minor);
            app.increment_diameter_label.Text = num2str(app.increment_diameter.Value);
            
        end

        % Value changed function: increment_thickness
        function increment_thicknessValueChanged(app, event)
            % Snap increment value to nearest tick on slider
            [~, idx_minor] = min(abs(event.Source.Value - event.Source.MinorTicks(:)));

            event.Source.Value = event.Source.MinorTicks(idx_minor);
            app.increment_thickness.Value = event.Source.MinorTicks(idx_minor);
            app.increment_thickness_label.Text = num2str(app.increment_thickness.Value);
            
        end

        % Value changed function: SuggestionsDropDown
        function SuggestionsDropDownValueChanged(app, event)
            % Populate grid based on selected manufacturing method
            value = app.SuggestionsDropDown.Value;
            app.SuggestionsTable.Data = app.Suggestions{value,2}(:,1:6);
        end

        % Value changed function: ValueCheckBox
        function ValueCheckBoxValueChanged(app, event)
            % Input predictor variables as one value not range
            % Disable range option
            value = app.ValueCheckBox.Value;
            if value == 1
                children = app.PredictorValuePanel.Children;
                set(children, 'Enable', 'on');
                app.RangeCheckBox.Value = 0;

                children = app.PredictorRangePanel.Children;
                set(children, 'Enable', 'off');
            else
                children = app.PredictorValuePanel.Children;
                set(children, 'Enable', 'on');
            end
        end

        % Value changed function: RangeCheckBox
        function RangeCheckBoxValueChanged(app, event)
            % Input predictor variables are a range with an increment
            % Disable value option
            value = app.RangeCheckBox.Value;
            if value == 1
                children = app.PredictorRangePanel.Children;
                set(children, 'Enable', 'on');
                app.ValueCheckBox.Value = 0;

                children = app.PredictorValuePanel.Children;
                set(children, 'Enable', 'off');
            else
                children = app.PredictorRangePanel.Children;
                set(children, 'Enable', 'off');
            end
        end

        % Value changed function: StandardIncrementCheckBox
        function StandardIncrementCheckBoxValueChanged(app, event)
            % Round min diameter and max diameter to nearest 1/16" and
            % increment by 1/16"
            % Only if using inches for values
            if app.StandardIncrementCheckBox.Value == 1
                app.diameter_min.Value = conditionalFcn(app, app.diameter_min.Value < 0.06, 0.0625, round(app.diameter_min.Value*16)/16);
                app.diameter_max.Value = round(app.diameter_max.Value*16)/16;
                app.increment_diameter.Value = 0.0625;
                app.increment_diameter_label.Text = '0.0625';
            end
        end

        % Value changed function: diameter_max, diameter_min
        function diameter_minValueChanged(app, event)
            app.StandardIncrementCheckBox.Value = 0;
        end

        % Value changed function: UsemetricvaluescmCheckBox
        function UsemetricvaluescmCheckBoxValueChanged(app, event)
            if app.UsemetricvaluescmCheckBox.Value == 1
                % % Using cm
                % Change labels and column names
                app.LengthinchEditFieldLabel.Text = "Length (cm)";
                app.DiameterinchEditFieldLabel.Text = "Diameter (cm)";
                app.ThicknessinchEditFieldLabel.Text = "Thickness (cm)";
                app.length_min_label.Text = "Length (cm)";
                app.diameter_min_label.Text = "Diameter (cm)";
                app.thickness_min_label.Text = "Thickness (cm)";
                app.StandardIncrementCheckBox.Enable = "off";
                app.StandardIncrementCheckBox.Value = 0;

                app.SuggestionsTable.ColumnName = ["Pressure (mBar)", "Length (cm)", "Diameter (cm)", "Thickness (cm)", "Manufacturing Method", "Force (N)"];

                % Change existing input values
                if app.ValueCheckBox.Value == 1
                    app.LengthinchEditField.Value = convertToCm(app, app.LengthinchEditField.Value);
                    app.DiameterinchEditField.Value = convertToCm(app, app.DiameterinchEditField.Value);
                    app.ThicknessinchEditField.Value = convertToCm(app, app.ThicknessinchEditField.Value);
                elseif app.RangeCheckBox.Value == 1
                    app.length_min.Value = convertToCm(app, app.length_min.Value);
                    app.length_max.Value = convertToCm(app, app.length_max.Value);
                    app.diameter_min.Value = convertToCm(app, app.diameter_min.Value);
                    app.diameter_max.Value = convertToCm(app, app.diameter_max.Value);
                    app.thickness_min.Value = convertToCm(app, app.thickness_min.Value);
                    app.thickness_max.Value = convertToCm(app, app.thickness_max.Value);
                end
                
                % Change suggestion units
                for i = 1:height(app.Suggestions)
                    app.Suggestions{i,2}(:,2:4) = app.Suggestions{i,2}(:,2:4) .* 2.54;
                end
                
                selected_manuf = app.SuggestionsDropDown.Value;
                if isnumeric(selected_manuf) && selected_manuf > 0
                  app.SuggestionsTable.Data = app.Suggestions{selected_manuf,2}(:,1:6);
                end
            else
                % % Using inches
                % Change labels and column names
                app.LengthinchEditFieldLabel.Text = "Length (inch)";
                app.DiameterinchEditFieldLabel.Text = "Diameter (inch)";
                app.ThicknessinchEditFieldLabel.Text = "Thickness (inch)";
                app.length_min_label.Text = "Length (inch)";
                app.diameter_min_label.Text = "Diameter (inch)";
                app.thickness_min_label.Text = "Thickness (inch)";
                
                app.SuggestionsTable.ColumnName = ["Pressure (mBar)", "Length (inch)", "Diameter (inch)", "Thickness (inch)", "Manufacturing Method", "Force (N)"];

                if app.ValueCheckBox.Value == 1
                    app.LengthinchEditField.Value = convertToInch(app, app.LengthinchEditField.Value);
                    app.DiameterinchEditField.Value = convertToInch(app, app.DiameterinchEditField.Value);
                    app.ThicknessinchEditField.Value = convertToInch(app, app.ThicknessinchEditField.Value);
                elseif app.RangeCheckBox.Value == 1
                    app.length_min.Value = convertToInch(app, app.length_min.Value);
                    app.length_max.Value = convertToInch(app, app.length_max.Value);
                    app.diameter_min.Value = convertToInch(app, app.diameter_min.Value);
                    app.diameter_max.Value = convertToInch(app, app.diameter_max.Value);
                    app.thickness_min.Value = convertToInch(app, app.thickness_min.Value);
                    app.thickness_max.Value = convertToInch(app, app.thickness_max.Value);
                    app.StandardIncrementCheckBox.Enable = "on";
                end

                % Change suggestion units
                for i = 1:height(app.Suggestions)
                    app.Suggestions{i,2}(:,2:4) = app.Suggestions{i,2}(:,2:4) .* 0.393701;
                end

                selected_manuf = app.SuggestionsDropDown.Value;
                if isnumeric(selected_manuf) && selected_manuf > 0
                   app.SuggestionsTable.Data = app.Suggestions{selected_manuf,2}(:,1:6);
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.PredictorRangePanel = uipanel(app.OptionsPanel);
            app.PredictorRangePanel.Title = 'Predictor Range';
            app.increment_length_label = uilabel(app.PredictorRangePanel);
            app.increment_length_label.Enable = 'off';
            app.increment_length_label.Text = '2';
            app.increment_diameter_label = uilabel(app.PredictorRangePanel);
            app.increment_diameter_label.Enable = 'off';
            app.increment_diameter_label.Text = '0.01';
            app.increment_thickness_label = uilabel(app.PredictorRangePanel);
            app.increment_thickness_label.Enable = 'off';
            app.increment_thickness_label.Text = '0.0001';
            app.length_min_label = uilabel(app.PredictorRangePanel);
            app.length_min_label.Enable = 'off';
            app.length_min_label.Text = 'Length (inch):';
            app.length_min = uieditfield(app.PredictorRangePanel, 'numeric');
            app.length_min.LowerLimitInclusive = 'off';
            app.length_min.Limits = [0 Inf];
            app.length_min.Enable = 'off';
            app.length_min.Value = 1;
            app.length_max_label = uilabel(app.PredictorRangePanel);
            app.length_max_label.Enable = 'off';
            app.length_max_label.Text = '-';
            app.length_max = uieditfield(app.PredictorRangePanel, 'numeric');
            app.length_max.LowerLimitInclusive = 'off';
            app.length_max.Limits = [0 Inf];
            app.length_max.Enable = 'off';
            app.length_max.Value = 12;
            app.diameter_min = uieditfield(app.PredictorRangePanel, 'numeric');
            app.diameter_min.LowerLimitInclusive = 'off';
            app.diameter_min.Limits = [0 Inf];
            app.diameter_min.ValueChangedFcn = createCallbackFcn(app, @diameter_minValueChanged, true);
            app.diameter_min.Enable = 'off';
            app.diameter_min.Value = 0.01;
            app.diameter_min_label = uilabel(app.PredictorRangePanel);
            app.diameter_min_label.Enable = 'off';
            app.diameter_min_label.Text = 'Diameter (inch):';
            app.diameter_max_label = uilabel(app.PredictorRangePanel);
            app.diameter_max_label.Enable = 'off';
            app.diameter_max_label.Text = '-';
            app.diameter_max = uieditfield(app.PredictorRangePanel, 'numeric');
            app.diameter_max.LowerLimitInclusive = 'off';
            app.diameter_max.Limits = [0 Inf];
            app.diameter_max.ValueChangedFcn = createCallbackFcn(app, @diameter_minValueChanged, true);
            app.diameter_max.Enable = 'off';
            app.diameter_max.Value = 0.1;
            app.thickness_min = uieditfield(app.PredictorRangePanel, 'numeric');
            app.thickness_min.Enable = 'off';
            app.thickness_min.Value = 0.01;
            app.thickness_min_label = uilabel(app.PredictorRangePanel);
            app.thickness_min_label.Enable = 'off';
            app.thickness_min_label.Text = 'Thickness (inch):';
            app.thickness_max_label = uilabel(app.PredictorRangePanel);
            app.thickness_max_label.Enable = 'off';
            app.thickness_max_label.Text = '-';
            app.thickness_max = uieditfield(app.PredictorRangePanel, 'numeric');
            app.thickness_max.Enable = 'off';
            app.thickness_max.Value = 0.02;
            app.increment_length_slider_label = uilabel(app.PredictorRangePanel);
            app.increment_length_slider_label.Enable = 'off';
            app.increment_length_slider_label.Text = 'Increment by:';
            app.increment_length = uislider(app.PredictorRangePanel);
            app.increment_length.Limits = [0 5];
            app.increment_length.MajorTicks = [0 1 2 3 4 5];
            app.increment_length.MajorTickLabels = {'0', '1', '2', '3', '4', '5'};
            app.increment_length.ValueChangedFcn = createCallbackFcn(app, @increment_lengthValueChanged, true);
            app.increment_length.MinorTicks = [0 0.25 0.5 0.75 1 1.25 1.5 1.75 2 2.25 2.5 2.75 3 3.25 3.5 3.75 4 4.25 4.5 4.75 5];
            app.increment_length.Tag = 'increment_length';
            app.increment_length.Enable = 'off';
            app.increment_length.Value = 2;
            app.increment_diameter_slider_label = uilabel(app.PredictorRangePanel);
            app.increment_diameter_slider_label.Enable = 'off';
            app.increment_diameter_slider_label.Text = 'Increment by: ';
            app.increment_diameter = uislider(app.PredictorRangePanel);
            app.increment_diameter.Limits = [0 0.25];
            app.increment_diameter.MajorTicks = [0 0.05 0.1 0.15 0.2 0.25];
            app.increment_diameter.MajorTickLabels = {'0', '0.05', '0.10', '0.15', '0.20', '0.25'};
            app.increment_diameter.ValueChangedFcn = createCallbackFcn(app, @increment_diameterValueChanged, true);
            app.increment_diameter.MinorTicks = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25];
            app.increment_diameter.Tag = 'increment_length';
            app.increment_diameter.Enable = 'off';
            app.increment_diameter.Value = 0.01;
            app.increment_thickness_slider_label = uilabel(app.PredictorRangePanel);
            app.increment_thickness_slider_label.Enable = 'off';
            app.increment_thickness_slider_label.Text = 'Increment by:';
            app.increment_thickness = uislider(app.PredictorRangePanel);
            app.increment_thickness.Limits = [0 0.001];
            app.increment_thickness.MajorTicks = [0 0.0002 0.0004 0.0006 0.0008 0.001];
            app.increment_thickness.MajorTickLabels = {'0', '0.0002', '0.0004', '0.0006', '0.0008', '0.001'};
            app.increment_thickness.ValueChangedFcn = createCallbackFcn(app, @increment_thicknessValueChanged, true);
            app.increment_thickness.MinorTicks = [0 0.0001 0.0002 0.0003 0.0004 0.0005 0.0006 0.0007 0.0008 0.0009 0.001];
            app.increment_thickness.Tag = 'increment_length';
            app.increment_thickness.Enable = 'off';
            app.increment_thickness.Value = 0.0001;
            app.StandardIncrementCheckBox = uicheckbox(app.PredictorRangePanel);
            app.StandardIncrementCheckBox.ValueChangedFcn = createCallbackFcn(app, @StandardIncrementCheckBoxValueChanged, true);
            app.StandardIncrementCheckBox.Enable = 'off';
            app.StandardIncrementCheckBox.Text = 'Use standard increments (1/16" in)';
            app.PredictorValuePanel = uipanel(app.OptionsPanel);
            app.PredictorValuePanel.Title = 'Predictor Value';
            app.LengthinchEditFieldLabel = uilabel(app.PredictorValuePanel);
            app.LengthinchEditFieldLabel.Enable = 'off';
            app.LengthinchEditFieldLabel.Text = 'Length (inch): ';
            app.LengthinchEditField = uieditfield(app.PredictorValuePanel, 'numeric');
            app.LengthinchEditField.LowerLimitInclusive = 'off';
            app.LengthinchEditField.Limits = [0 Inf];
            app.LengthinchEditField.ValueDisplayFormat = '%.2f';
            app.LengthinchEditField.Enable = 'off';
            app.LengthinchEditField.Value = 1;
            app.DiameterinchEditFieldLabel = uilabel(app.PredictorValuePanel);
            app.DiameterinchEditFieldLabel.Enable = 'off';
            app.DiameterinchEditFieldLabel.Text = 'Diameter (inch): ';
            app.DiameterinchEditField = uieditfield(app.PredictorValuePanel, 'numeric');
            app.DiameterinchEditField.LowerLimitInclusive = 'off';
            app.DiameterinchEditField.Limits = [0 Inf];
            app.DiameterinchEditField.ValueDisplayFormat = '%.4f';
            app.DiameterinchEditField.Enable = 'off';
            app.DiameterinchEditField.Value = 1;
            app.ThicknessinchEditFieldLabel = uilabel(app.PredictorValuePanel);
            app.ThicknessinchEditFieldLabel.Enable = 'off';
            app.ThicknessinchEditFieldLabel.Text = 'Thickness (inch):';
            app.ThicknessinchEditField = uieditfield(app.PredictorValuePanel, 'numeric');
            app.ThicknessinchEditField.LowerLimitInclusive = 'off';
            app.ThicknessinchEditField.Limits = [0 Inf];
            app.ThicknessinchEditField.ValueDisplayFormat = '%.4f';
            app.ThicknessinchEditField.Enable = 'off';
            app.ThicknessinchEditField.Value = 1;
            app.LatexSheetCheckBox = uicheckbox(app.OptionsPanel);
            app.LatexSheetCheckBox.Text = 'Latex Sheet';
            app.LatexTubeCheckBox = uicheckbox(app.OptionsPanel);
            app.LatexTubeCheckBox.Text = 'Latex Tube';
            app.BEMISRolledCheckBox = uicheckbox(app.OptionsPanel);
            app.BEMISRolledCheckBox.Text = 'BEMIS Rolled';
            app.ManufacturingMethodsLabel = uilabel(app.OptionsPanel);
            app.ManufacturingMethodsLabel.Text = {'Manufacturing'; 'Methods:'};
            app.force = uieditfield(app.OptionsPanel, 'numeric');
            app.force.LowerLimitInclusive = 'off';
            app.force.Limits = [0 Inf];
            app.force.Value = 1;
            app.pressure_min = uieditfield(app.OptionsPanel, 'numeric');
            app.pressure_min.Limits = [1 Inf];
            app.pressure_min.RoundFractionalValues = 'on';
            app.pressure_min.ValueDisplayFormat = '%11g';
            app.pressure_min.Value = 1;
            app.pressure_max = uieditfield(app.OptionsPanel, 'numeric');
            app.pressure_max.LowerLimitInclusive = 'off';
            app.pressure_max.Limits = [0 Inf];
            app.pressure_max.RoundFractionalValues = 'on';
            app.pressure_max.ValueDisplayFormat = '%11g';
            app.pressure_max.Value = 1000;
            app.Label_2 = uilabel(app.OptionsPanel);
            app.Label_2.Text = '-';
            app.PressureRangemBarLabel = uilabel(app.OptionsPanel);
            app.PressureRangemBarLabel.Text = 'Pressure Range (mBar):';
            app.DesiredForceNLabel = uilabel(app.OptionsPanel);
            app.DesiredForceNLabel.Text = 'Desired Force (N):';
            app.ValueCheckBox = uicheckbox(app.OptionsPanel);
            app.ValueCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValueCheckBoxValueChanged, true);
            app.ValueCheckBox.Text = '';
            app.RangeCheckBox = uicheckbox(app.OptionsPanel);
            app.RangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RangeCheckBoxValueChanged, true);
            app.RangeCheckBox.Text = '';
            app.RipstopRolledCheckBox = uicheckbox(app.OptionsPanel);
            app.RipstopRolledCheckBox.Text = 'Ripstop Rolled';
            app.GenerateSuggestionsButton = uibutton(app.OptionsPanel, 'push');
            app.GenerateSuggestionsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSuggestionsButtonPushed, true);
            app.GenerateSuggestionsButton.Text = 'Generate Suggestions';
            app.UsemetricvaluescmCheckBox = uicheckbox(app.OptionsPanel);
            app.UsemetricvaluescmCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsemetricvaluescmCheckBoxValueChanged, true);
            app.UsemetricvaluescmCheckBox.Text = 'Use metric values (cm)';
            app.LatexDipCheckBox = uicheckbox(app.OptionsPanel);
            app.LatexDipCheckBox.Text = 'Latex Dip';
            app.SuggestionsPanel = uipanel(app.UIFigure);
            app.SuggestionsPanel.Title = 'Suggestions';
            app.SuggestionsTable = uitable(app.SuggestionsPanel);
            app.SuggestionsTable.ColumnName = {'Pressure (mBar)'; 'Length (in)'; 'Diameter (in)'; 'Thickness (in)'; 'Manufacturing Method'; 'Force (N)'};
            app.SuggestionsTable.RowName = {};
            app.SuggestionsTable.SelectionType = 'row';
            app.SuggestionsTable.Enable = 'off';
            app.SuggestionsDropDown = uidropdown(app.SuggestionsPanel);
            app.SuggestionsDropDown.Items = {'--------'};
            app.SuggestionsDropDown.ValueChangedFcn = createCallbackFcn(app, @SuggestionsDropDownValueChanged, true);
            app.SuggestionsDropDown.Enable = 'off';
            app.SuggestionsDropDown.Value = '--------';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ForceSight_LogoNoText.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'ForceSight_LogoJustText.png');
            app.V20Label = uilabel(app.UIFigure);
            app.V20Label.Text = 'V2.0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ForceSight_AppDesignerFile

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end